classdef RhythmApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveAsMenu                      matlab.ui.container.Menu
        ExportRatingsMenu               matlab.ui.container.Menu
        AudioScrubbingPanel             matlab.ui.container.Panel
        PausePlay                       matlab.ui.control.Button
        Slider                          matlab.ui.control.Slider
        TabGroup                        matlab.ui.container.TabGroup
        VisualizeTab                    matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        RatingsTab                      matlab.ui.container.Tab
        StandardRatingsPanel            matlab.ui.container.Panel
        TotalmsErrorEditField           matlab.ui.control.NumericEditField
        TotalmsErrorEditFieldLabel      matlab.ui.control.Label
        OverUnderEditField              matlab.ui.control.NumericEditField
        OverUnderLabel                  matlab.ui.control.Label
        ExerciseRatingEditField         matlab.ui.control.NumericEditField
        ExerciseRatingEditFieldLabel    matlab.ui.control.Label
        SynchronizationRatingEditField  matlab.ui.control.NumericEditField
        SynchronizationRatingEditFieldLabel  matlab.ui.control.Label
        PeriodRatingEditField           matlab.ui.control.NumericEditField
        PeriodRatingEditFieldLabel      matlab.ui.control.Label
        AgeAdjustedRatingPanel          matlab.ui.container.Panel
        AgeRatingEditField              matlab.ui.control.NumericEditField
        AgeRatingEditFieldLabel         matlab.ui.control.Label
        ParticipantAgeEditField         matlab.ui.control.NumericEditField
        ParticipantAgeEditFieldLabel    matlab.ui.control.Label
        ChooseFileButton                matlab.ui.control.Button
        FileStatusEditField             matlab.ui.control.EditField
        FileStatusEditFieldLabel        matlab.ui.control.Label
        FilePathEditField               matlab.ui.control.EditField
        FilePathLabel                   matlab.ui.control.Label
        TruthControlPanel               matlab.ui.container.Panel
        ThresholdKnob                   matlab.ui.control.Knob
        ThresholdKnobLabel              matlab.ui.control.Label
        LoadExercisesButton             matlab.ui.control.Button
        ExerciseDropDown                matlab.ui.control.DropDown
        ExerciseDropDownLabel           matlab.ui.control.Label
        ParticipantControlPanel         matlab.ui.container.Panel
        StopForward                     matlab.ui.control.Button
        StopBackward                    matlab.ui.control.Button
        StartForward                    matlab.ui.control.Button
        StartBackward                   matlab.ui.control.Button
        StopTimeLabel                   matlab.ui.control.Label
        StartTimeLabel                  matlab.ui.control.Label
        StopMinEditField                matlab.ui.control.NumericEditField
        MinuteEditField_2Label          matlab.ui.control.Label
        StopHourEditField               matlab.ui.control.NumericEditField
        HourEditField_2Label            matlab.ui.control.Label
        StopSecEditField                matlab.ui.control.NumericEditField
        SecondEditField_2Label          matlab.ui.control.Label
        StartSecEditField               matlab.ui.control.NumericEditField
        SecondEditFieldLabel            matlab.ui.control.Label
        StartMinEditField               matlab.ui.control.NumericEditField
        MinuteEditFieldLabel            matlab.ui.control.Label
        StartHourEditField              matlab.ui.control.NumericEditField
        HourEditFieldLabel              matlab.ui.control.Label
        AnalyzeButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        BigSignal % signal read in from the audio file the user specifies
        SamplingRate % the sampling rate in Hz
        TruthSignal % cell array containing truth values for all exercises

        RhythmRatings % vector containing the accuracy scores for rhythm
        TimingRatings % vecotr containing the accuracy scores for timing
        ExerciseRatings % vector containing the ratings for all exercises
        AgeRatings % vector containing the age ratings for all exercises
        OverUnders % vector containing the over & unders for all exercises
        MsErrors % vector containing the ms errors for all exercises
        
        AudioPlay % audioplayer object to be played
        ExerciseGraphs % cell array containing the graphs for all exercises
        TruthGraphs % cell array containing the graphs for the updated truths
        MetroGraphs % cell array containing the graphs for the metronome
        AgeEquation % vector containing info for the age adjustments

        StartTimes % cell array containing the start times for all exercises
        StopTimes % cell array containing the stop times for all exercises
    end
    
    methods (Access = private)
        
        function UpdatePlots(app)
            index = str2double(app.ExerciseDropDown.Value);
            metro = app.MetroGraphs{index};
            if isempty(metro)
                sizeTruth = size(app.TruthSignal);
                if sizeTruth(1) == 30
                    x1 = size(app.TruthSignal{index});
                    stem(app.UIAxes2, 1:x1(2), app.TruthSignal{index});
                    xlim(app.UIAxes2, [0 x1(2)+5]);
                    ylim(app.UIAxes2, [0.1 1.25]);
                end

                stem(app.UIAxes, 0:0.2:1, [0 0 0 0 0 0]);
                ylim(app.UIAxes, [0 1]);
            else
                % plot the result in the participant figure
                rawList = app.ExerciseGraphs{index};
                nTruth = app.TruthGraphs{index};

                x1 = size(rawList);
                stem(app.UIAxes, 1:x1(2), rawList);
                hold(app.UIAxes,'on');
                stem(app.UIAxes, 1:length(metro), metro);
                hold(app.UIAxes,'off');
                xlim(app.UIAxes, [0 x1(2)+5]);
                ylim(app.UIAxes, [0.1 1.25]);

                stem(app.UIAxes2, 1:length(nTruth), nTruth);
                hold(app.UIAxes2,'on');
                stem(app.UIAxes2, 1:length(metro), metro);
                hold(app.UIAxes2,'off');
                xlim(app.UIAxes2, [0 x1(2)+5]);
                ylim(app.UIAxes2, [0.1 1.25]); 
            end          
                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            % initialize global variables to 30x1 cell arrays
            app.RhythmRatings = cell(30,1);
            app.TimingRatings = cell(30,1);
            app.ExerciseGraphs = cell(30,1);
            app.TruthGraphs = cell(30,1);
            app.MetroGraphs = cell(30,1);
            app.ExerciseRatings = cell(30,1);
            app.AgeRatings = cell(30,1);
            app.OverUnders = cell(30,1);
            app.MsErrors = cell(30,1);
            app.AgeEquation = AgeModel();
            app.StartTimes = cell(30,1);
            app.StopTimes = cell(30,1);
        end

        % Value changed function: ExerciseDropDown
        function ExerciseDropDownValueChanged(app, event)
            % if the exercise drop down menu gets a new value, update
            % the graphs and ratings displayed on the GUI
            UpdatePlots(app);
            index = str2double(app.ExerciseDropDown.Value);
            app.AnalyzeButton.Text = "Analyze E" + app.ExerciseDropDown.Value;

            if size(app.RhythmRatings{index},1) > 0
                app.PeriodRatingEditField.Value = app.RhythmRatings{index};
            else
                app.PeriodRatingEditField.Value = 0;
            end

            if size(app.TimingRatings{index},1) > 0
                app.SynchronizationRatingEditField.Value = app.TimingRatings{index};
            else
                app.SynchronizationRatingEditField.Value = 0;
            end

            if size(app.ExerciseRatings{index},1) > 0
                app.ExerciseRatingEditField.Value = app.ExerciseRatings{index};
            else
                app.ExerciseRatingEditField.Value = 0;
            end

            if size(app.AgeRatings{index},1) > 0
                app.AgeRatingEditField.Value = app.AgeRatings{index};
            else
                app.AgeRatingEditField.Value = 0;
            end

            if size(app.OverUnders{index},1) > 0
                app.OverUnderEditField.Value = app.OverUnders{index};
            else
                app.OverUnderEditField.Value = 0;
            end

            if size(app.MsErrors{index},1) > 0
                app.TotalmsErrorEditField.Value = app.MsErrors{index};
            else
                app.TotalmsErrorEditField.Value = 0;
            end

        end

        % Button pushed function: LoadExercisesButton
        function LoadExercisesButtonPushed(app, event)
            % reads in the GetTruth.mat file that contains the expected
            % values for all exercises
            try
                app.TruthSignal = load('GetTruth.mat', 'truthArray');
                app.TruthSignal = app.TruthSignal.truthArray;
            catch
                app.TruthSignal = GetTruth();
            end
            
            % store them in the TruthSignal variable and update graphs
            for ii = 1:30
                app.TruthSignal{ii} = [zeros(1,149) app.TruthSignal{ii} zeros(1,149)];
            end
            UpdatePlots(app);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            try
                index = str2double(app.ExerciseDropDown.Value);
                
                % find the start time in seconds
                startSecs = app.StartSecEditField.Value;
                startSecs = startSecs + (app.StartMinEditField.Value * 60);
                startSecs = startSecs + (app.StartHourEditField.Value * 60 * 60);
                
                app.StartTimes{index} = string(app.StartHourEditField.Value) + ":" + string(app.StartMinEditField.Value);
                app.StartTimes{index} = app.StartTimes{index} + ":" + string(app.StartSecEditField.Value);

                % convert the time in seconds to the sample index
                startIndex = startSecs * app.SamplingRate;
                
                % find the stop time in seconds
                stopSecs = app.StopSecEditField.Value;
                stopSecs = stopSecs + (app.StopMinEditField.Value * 60);
                stopSecs = stopSecs + (app.StopHourEditField.Value * 60);
                
                app.StopTimes{index} = string(app.StopHourEditField.Value) + ":" + string(app.StopMinEditField.Value);
                app.StopTimes{index} = app.StopTimes{index} + ":" + string(app.StopSecEditField.Value);

                % convert the time in seconds to the sample index
                stopIndex = stopSecs * app.SamplingRate;
                
                % pass the desired signal into the filter
                signal = app.BigSignal(startIndex:stopIndex,:);
                [eList, rawList] = FilterSignal_v04(signal, app.SamplingRate, app.ThresholdKnob.Value);
                
                eList = [zeros(1,149) eList zeros(1,149)];

                truth = app.TruthSignal{index};

                % get the rhythm rating
                [truth, nPerf] = ShrinkStretch(truth, eList);
                nPerf = [zeros(1,149) nPerf zeros(1,149)];
                rRate = RhythmRatings_v02(truth, nPerf, 100);
                app.RhythmRatings{index} = rRate;
                app.PeriodRatingEditField.Value = app.RhythmRatings{index};

                % get the timing rating
                metro = FilterMetronome(signal, app.SamplingRate, index);
                [tRate, nTruth] = TimingRatings(truth, rawList, metro, index, 100);
                app.TimingRatings{index} = tRate;
                app.SynchronizationRatingEditField.Value = app.TimingRatings{index};
                

                % get the exercise rating
                app.ExerciseRatings{index} = (app.RhythmRatings{index} + app.TimingRatings{index})/2;
                app.ExerciseRatingEditField.Value = app.ExerciseRatings{index};

                % get the age rating
                if app.ParticipantAgeEditField.Value ~= 0
                    thresh = app.AgeEquation(app.ParticipantAgeEditField.Value+1);
                    thresh = round(100 + (thresh-25));
                    if thresh > 140
                        thresh = 140;
                    end
    
                    arRate = RhythmRatings_v02(truth, nPerf, thresh);
                    [atRate, ~] = TimingRatings(truth, rawList, metro, index, thresh);
                    app.AgeRatings{index} = (arRate+atRate)/2;
                    app.AgeRatingEditField.Value = app.AgeRatings{index};
                end

                % get the over under
                app.OverUnderEditField.Value = sum(rawList) - sum(truth);
                app.OverUnders{index} = sum(rawList) - sum(truth);

                % calculate and display total ms error
                tClaps = find(nTruth);
                pClaps = find(rawList);
                
                msError = 0;
                for ii = 1:length(pClaps)
                    if ii <= length(tClaps)
                        msError = msError + abs(tClaps(ii) - pClaps(ii));
                    else
                        msError = msError + abs(tClaps(end) - pClaps(ii));
                    end
                end
                app.TotalmsErrorEditField.Value = msError;


                % save the raw filtered graph
                app.ExerciseGraphs{index} = rawList;
                app.TruthGraphs{index} = nTruth;
                app.MetroGraphs{index} = metro;
                app.MsErrors{index} = msError;

                % update the plots
                UpdatePlots(app);
                
            catch ME
                disp(ME);
            end

        end

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            % opens up the file explorer and lets user choose an mp3 or wav
            try
                app.FileStatusEditField.Value = "Attempting to Open File. Please Wait...";
            
                [file, path] = uigetfile({'*.mp3'; '*.wav'});
                path = [path file];
                if file ~= 0
                    [app.BigSignal, app.SamplingRate] = audioread(path);
                    text = "File Read Successfully";
                    app.FilePathEditField.Value = path;
                    
                    % sets the time slider at the bottom
                    app.BigSignal = [app.BigSignal; zeros(1000000,2)];
                    seconds = size(app.BigSignal,1) / app.SamplingRate;
                    app.Slider.Limits = [0 seconds/60];
                else
                    text = "File Not Selected. Please Try Again";
                end
                
                app.FileStatusEditField.Value = text;
                
            catch ME
                disp(ME);
            end
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            % updates the start and stop times for the audio sample
            value = app.Slider.Value;
            
            startHour = floor(value / 60);
            startMin = floor(value - (60*startHour));
            startSec = mod(value - (60*startHour), startMin);
            startSec = round(60 * startSec);
            
            app.StartHourEditField.Value = startHour;
            app.StartMinEditField.Value = startMin;
            app.StartSecEditField.Value = startSec;
            
            stopSec = startSec + 10;
            app.StopHourEditField.Value = startHour;
            app.StopMinEditField.Value = startMin + floor(mod(stopSec/60, 60));
            app.StopSecEditField.Value = mod(stopSec,60);
        end

        % Button pushed function: StartBackward
        function StartBackwardButtonPushed(app, event)
            % decrements the sample start time by 1 second
            if app.StartSecEditField.Value ~= 0
                app.StartSecEditField.Value = app.StartSecEditField.Value - 1;
            else
                app.StartSecEditField.Value = 59;
                app.StartMinEditField.Value = app.StartMinEditField.Value - 1;
            end
        end

        % Button pushed function: StartForward
        function StartForwardButtonPushed(app, event)
            % increments the sample start time by 1 second
            if app.StartSecEditField.Value ~= 59
                app.StartSecEditField.Value = app.StartSecEditField.Value + 1;
            else
                app.StartSecEditField.Value = 0;
                app.StartMinEditField.Value = app.StartMinEditField.Value + 1;
            end
        end

        % Button pushed function: StopBackward
        function StopBackwardButtonPushed(app, event)
            % decrements the sample stop time by 1 second
            if app.StopSecEditField.Value ~= 0
                app.StopSecEditField.Value = app.StopSecEditField.Value - 1;
            else
                app.StopSecEditField.Value = 59;
                app.StopMinEditField.Value = app.StopMinEditField.Value - 1;
            end
        end

        % Button pushed function: StopForward
        function StopForwardButtonPushed(app, event)
            % increments the sample stop time by 1 second
            if app.StopSecEditField.Value ~= 59
                app.StopSecEditField.Value = app.StopSecEditField.Value + 1;
            else
                app.StopSecEditField.Value = 0;
                app.StopMinEditField.Value = app.StopMinEditField.Value + 1;
            end
        end

        % Button pushed function: PausePlay
        function PausePlayButtonPushed(app, event)
            try
                % find the start time in seconds
                startSecs = app.StartSecEditField.Value;
                startSecs = startSecs + (app.StartMinEditField.Value * 60);
                startSecs = startSecs + (app.StartHourEditField.Value * 60 * 60);
                
                % convert the time in seconds to the sample index
                startIndex = startSecs * app.SamplingRate;
                
                % find the stop time in seconds
                stopSecs = app.StopSecEditField.Value;
                stopSecs = stopSecs + (app.StopMinEditField.Value * 60);
                stopSecs = stopSecs + (app.StopHourEditField.Value * 60);
                
                % convert the time in seconds to the sample index
                stopIndex = (stopSecs+10) * app.SamplingRate;
                
                playFile = app.BigSignal(startIndex+1:stopIndex,:);
                %sound(playFile, app.SamplingRate, 16)
                app.AudioPlay = audioplayer(playFile, app.SamplingRate);
                
                play(app.AudioPlay);
                
                % play the sample for the exact amount of time it should be
                % played. afterwards, stop the audio
                delay = (app.StopMinEditField.Value - app.StartMinEditField.Value) * 60;
                delay = delay + (app.StopSecEditField.Value - app.StartSecEditField.Value);
                pause(delay);
                
                stop(app.AudioPlay);
            catch ME
                disp(ME);
            end
            
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            % save all global variables in a .mat file
            try
                filename = app.FilePathEditField.Value;
                filename = [filename(end-10:end-4) '.mat'];
                BigSignal = app.BigSignal;
                SamplingRate = app.SamplingRate;
                TruthSignal = app.TruthSignal;
                RhythmRating = app.RhythmRatings;
                TimingRating = app.TimingRatings;
                ExerciseRatings = app.ExerciseRatings;
                AgeRatings = app.AgeRatings;
                OverUnders = app.OverUnders;
                MsErrors = app.MsErrors;
                AudioPlay = app.AudioPlay;
                ExerciseGraphs = app.ExerciseGraphs;
                TruthGraphs = app.TruthGraphs;
                MetroGraphs = app.MetroGraphs;
                AgeEquation = app.AgeEquation;
    
                save(filename, 'BigSignal', 'SamplingRate',"TruthSignal", ...
                    "ExerciseRatings","AgeRatings","AudioPlay",...
                    "ExerciseGraphs", "RhythmRating", "TimingRating",...
                    "AgeEquation", "TruthGraphs", "MetroGraphs",...
                    "OverUnders", "MsErrors");
            catch
            end
            
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % load in global variables from a .mat file
            app.FileStatusEditField.Value = "Attempting to Open Project. Please Wait...";
            
            [file, path] = uigetfile('*.mat');
            path = [path file];
            if file ~= 0
                load(path);
                text = "Project Read Successfully";
                app.FilePathEditField.Value = path;
            else
                text = "Project Not Selected. Please Try Again";
            end

            app.FileStatusEditField.Value = text;

            try
                app.BigSignal = BigSignal;
                app.SamplingRate = SamplingRate;
                app.TruthSignal = TruthSignal;
                app.RhythmRatings = RhythmRating;
                app.TimingRatings = TimingRating;
                app.ExerciseRatings = ExerciseRatings;
                app.AgeRatings = AgeRatings;
                app.OverUnders = OverUnders;
                app.MsErrors = MsErrors;
                app.AudioPlay = AudioPlay;
                app.ExerciseGraphs = ExerciseGraphs;
                app.TruthGraphs = TruthGraphs;
                app.MetroGraphs = MetroGraphs;
                app.AgeEquation = AgeEquation;

                seconds = size(app.BigSignal,1) / app.SamplingRate;
                app.Slider.Limits = [0 seconds/60];

                UpdatePlots(app);
            catch
                text = "File Loaded is Not a Project. Please Select a Different File.";
                app.FileStatusEditField.Value = text;
            end
        end

        % Menu selected function: ExportRatingsMenu
        function ExportRatingsMenuSelected(app, event)
            % export a CSV of ratings and timings for all exercises
            filename = app.FilePathEditField.Value;
            filename = [filename(end-10:end-4) '.csv'];
            
            C = cell(30,1);
            for ii=1:30
                C{ii} = ii;
            end

            header = ["Exercise" "Period Rating" "Synchronization Rating" "Exercise Rating" "Age Rating" "Over/Under" "Total ms Error" "Start Time" "Stop Time"];
            expMat = [C app.RhythmRatings app.TimingRatings app.ExerciseRatings app.AgeRatings app.OverUnders app.MsErrors app.StartTimes app.StopTimes];
            
            writematrix(header, filename);
            writecell(expMat,filename,'WriteMode','append');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As...';
            app.ExportRatingsMenu = uimenu(app.FileMenu);
            app.ExportRatingsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportRatingsMenuSelected, true);
            app.ExportRatingsMenu.Text = 'Export Ratings';
            app.ParticipantControlPanel = uipanel(app.UIFigure);
            app.ParticipantControlPanel.Title = 'Participant Control';
            app.AnalyzeButton = uibutton(app.ParticipantControlPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.HourEditFieldLabel = uilabel(app.ParticipantControlPanel);
            app.HourEditFieldLabel.Text = 'Hour';
            app.StartHourEditField = uieditfield(app.ParticipantControlPanel, 'numeric');
            app.StartHourEditField.Editable = 'off';
            app.MinuteEditFieldLabel = uilabel(app.ParticipantControlPanel);
            app.MinuteEditFieldLabel.Text = 'Minute';
            app.StartMinEditField = uieditfield(app.ParticipantControlPanel, 'numeric');
            app.StartMinEditField.Editable = 'off';
            app.SecondEditFieldLabel = uilabel(app.ParticipantControlPanel);
            app.SecondEditFieldLabel.Text = 'Second';
            app.StartSecEditField = uieditfield(app.ParticipantControlPanel, 'numeric');
            app.StartSecEditField.Editable = 'off';
            app.SecondEditField_2Label = uilabel(app.ParticipantControlPanel);
            app.SecondEditField_2Label.Text = 'Second';
            app.StopSecEditField = uieditfield(app.ParticipantControlPanel, 'numeric');
            app.StopSecEditField.Editable = 'off';
            app.HourEditField_2Label = uilabel(app.ParticipantControlPanel);
            app.HourEditField_2Label.Text = 'Hour';
            app.StopHourEditField = uieditfield(app.ParticipantControlPanel, 'numeric');
            app.StopHourEditField.Editable = 'off';
            app.MinuteEditField_2Label = uilabel(app.ParticipantControlPanel);
            app.MinuteEditField_2Label.Text = 'Minute';
            app.StopMinEditField = uieditfield(app.ParticipantControlPanel, 'numeric');
            app.StopMinEditField.Editable = 'off';
            app.StartTimeLabel = uilabel(app.ParticipantControlPanel);
            app.StartTimeLabel.Text = 'Start Time:';
            app.StopTimeLabel = uilabel(app.ParticipantControlPanel);
            app.StopTimeLabel.Text = 'Stop Time:';
            app.StartBackward = uibutton(app.ParticipantControlPanel, 'push');
            app.StartBackward.ButtonPushedFcn = createCallbackFcn(app, @StartBackwardButtonPushed, true);
            app.StartBackward.Icon = 'back.png';
            app.StartBackward.Text = '';
            app.StartForward = uibutton(app.ParticipantControlPanel, 'push');
            app.StartForward.ButtonPushedFcn = createCallbackFcn(app, @StartForwardButtonPushed, true);
            app.StartForward.Icon = 'forward.png';
            app.StartForward.Text = '';
            app.StopBackward = uibutton(app.ParticipantControlPanel, 'push');
            app.StopBackward.ButtonPushedFcn = createCallbackFcn(app, @StopBackwardButtonPushed, true);
            app.StopBackward.Icon = 'back.png';
            app.StopBackward.Text = '';
            app.StopForward = uibutton(app.ParticipantControlPanel, 'push');
            app.StopForward.ButtonPushedFcn = createCallbackFcn(app, @StopForwardButtonPushed, true);
            app.StopForward.Icon = 'forward.png';
            app.StopForward.Text = '';
            app.TruthControlPanel = uipanel(app.UIFigure);
            app.TruthControlPanel.Title = 'Truth Control';
            app.ExerciseDropDownLabel = uilabel(app.TruthControlPanel);
            app.ExerciseDropDownLabel.Text = 'Exercise';
            app.ExerciseDropDown = uidropdown(app.TruthControlPanel);
            app.ExerciseDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30'};
            app.ExerciseDropDown.ValueChangedFcn = createCallbackFcn(app, @ExerciseDropDownValueChanged, true);
            app.ExerciseDropDown.Value = '1';
            app.LoadExercisesButton = uibutton(app.TruthControlPanel, 'push');
            app.LoadExercisesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExercisesButtonPushed, true);
            app.LoadExercisesButton.Text = 'Load Exercises';
            app.ThresholdKnobLabel = uilabel(app.TruthControlPanel);
            app.ThresholdKnobLabel.Text = 'Threshold';
            app.ThresholdKnob = uiknob(app.TruthControlPanel, 'continuous');
            app.ThresholdKnob.Limits = [0 500];
            app.ThresholdKnob.Value = 100;
            app.FilePathLabel = uilabel(app.UIFigure);
            app.FilePathLabel.Text = 'File Path:';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.Editable = 'off';
            app.FileStatusEditFieldLabel = uilabel(app.UIFigure);
            app.FileStatusEditFieldLabel.Text = 'File Status';
            app.FileStatusEditField = uieditfield(app.UIFigure, 'text');
            app.FileStatusEditField.Editable = 'off';
            app.ChooseFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VisualizeTab = uitab(app.TabGroup);
            app.VisualizeTab.Title = 'Visualize';
            app.UIAxes2 = uiaxes(app.VisualizeTab);
            title(app.UIAxes2, 'Truth')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.VisualizeTab);
            title(app.UIAxes, 'Participant')
            xlabel(app.UIAxes, 'Samples')
            zlabel(app.UIAxes, 'Z')
            app.RatingsTab = uitab(app.TabGroup);
            app.RatingsTab.Title = 'Ratings';
            app.AgeAdjustedRatingPanel = uipanel(app.RatingsTab);
            app.AgeAdjustedRatingPanel.Title = 'Age-Adjusted Rating';
            app.ParticipantAgeEditFieldLabel = uilabel(app.AgeAdjustedRatingPanel);
            app.ParticipantAgeEditFieldLabel.Text = 'Participant Age:';
            app.ParticipantAgeEditField = uieditfield(app.AgeAdjustedRatingPanel, 'numeric');
            app.AgeRatingEditFieldLabel = uilabel(app.AgeAdjustedRatingPanel);
            app.AgeRatingEditFieldLabel.Text = 'Age Rating';
            app.AgeRatingEditField = uieditfield(app.AgeAdjustedRatingPanel, 'numeric');
            app.AgeRatingEditField.Editable = 'off';
            app.StandardRatingsPanel = uipanel(app.RatingsTab);
            app.StandardRatingsPanel.Title = 'Standard Ratings';
            app.PeriodRatingEditFieldLabel = uilabel(app.StandardRatingsPanel);
            app.PeriodRatingEditFieldLabel.Text = 'Period Rating';
            app.PeriodRatingEditField = uieditfield(app.StandardRatingsPanel, 'numeric');
            app.PeriodRatingEditField.Editable = 'off';
            app.SynchronizationRatingEditFieldLabel = uilabel(app.StandardRatingsPanel);
            app.SynchronizationRatingEditFieldLabel.Text = 'Synchronization Rating';
            app.SynchronizationRatingEditField = uieditfield(app.StandardRatingsPanel, 'numeric');
            app.SynchronizationRatingEditField.Editable = 'off';
            app.ExerciseRatingEditFieldLabel = uilabel(app.StandardRatingsPanel);
            app.ExerciseRatingEditFieldLabel.Text = 'Exercise Rating';
            app.ExerciseRatingEditField = uieditfield(app.StandardRatingsPanel, 'numeric');
            app.ExerciseRatingEditField.Editable = 'off';
            app.OverUnderLabel = uilabel(app.StandardRatingsPanel);
            app.OverUnderLabel.Text = '# Over/Under';
            app.OverUnderEditField = uieditfield(app.StandardRatingsPanel, 'numeric');
            app.OverUnderEditField.Editable = 'off';
            app.TotalmsErrorEditFieldLabel = uilabel(app.StandardRatingsPanel);
            app.TotalmsErrorEditFieldLabel.Text = 'Total ms Error';
            app.TotalmsErrorEditField = uieditfield(app.StandardRatingsPanel, 'numeric');
            app.TotalmsErrorEditField.Editable = 'off';
            app.AudioScrubbingPanel = uipanel(app.UIFigure);
            app.AudioScrubbingPanel.Title = 'Audio Scrubbing';
            app.Slider = uislider(app.AudioScrubbingPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.PausePlay = uibutton(app.AudioScrubbingPanel, 'push');
            app.PausePlay.ButtonPushedFcn = createCallbackFcn(app, @PausePlayButtonPushed, true);
            app.PausePlay.Icon = 'pauseplay.png';
            app.PausePlay.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RhythmApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end